classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IPAddressEditField       matlab.ui.control.EditField
        IPAddressEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        ipaddr % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ipaddr)
            app.CallingApp = mainapp;
            app.IPAddressEditField.Value = ipaddr;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.updateIPaddr(app.ipaddr)
            app.CallingApp.ROS_MASTER_URIMenu.Enable = 'on';
            delete(app)
        end

        % Value changing function: IPAddressEditField
        function IPAddressEditFieldValueChanging(app, event)
            app.ipaddr = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IPAddressEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddressEditField.ValueChangingFcn = createCallbackFcn(app, @IPAddressEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end